classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Panel_2                    matlab.ui.container.Panel
        Image1                     matlab.ui.control.UIAxes
        LoadImageButton            matlab.ui.control.Button
        SaveImageButton            matlab.ui.control.Button
        AdjustGraphicsPanel        matlab.ui.container.Panel
        HSLRGBPanel                matlab.ui.container.Panel
        ButtonGroup                matlab.ui.container.ButtonGroup
        HSLButton                  matlab.ui.control.RadioButton
        RGBButton                  matlab.ui.control.RadioButton
        SaturationSliderLabel      matlab.ui.control.Label
        SaturationSlider           matlab.ui.control.Slider
        LightSliderLabel           matlab.ui.control.Label
        LightSlider                matlab.ui.control.Slider
        HueSliderLabel             matlab.ui.control.Label
        HueSlider                  matlab.ui.control.Slider
        RedSliderLabel             matlab.ui.control.Label
        RedSlider                  matlab.ui.control.Slider
        GreenSliderLabel           matlab.ui.control.Label
        GreenSlider                matlab.ui.control.Slider
        BlueSliderLabel            matlab.ui.control.Label
        BlueSlider                 matlab.ui.control.Slider
        ApplyButton                matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        HueCheckBox                matlab.ui.control.CheckBox
        SaturationCheckBox         matlab.ui.control.CheckBox
        LightCheckBox              matlab.ui.control.CheckBox
        RedCheckBox                matlab.ui.control.CheckBox
        GreenCheckBox              matlab.ui.control.CheckBox
        BlueCheckBox               matlab.ui.control.CheckBox
        SizeRotationPanel          matlab.ui.container.Panel
        ContentAwareResizingLabel  matlab.ui.control.Label
        CurrentHeightLabel         matlab.ui.control.Label
        CurrentWidthLabel          matlab.ui.control.Label
        heightLabel                matlab.ui.control.Label
        widthLabel                 matlab.ui.control.Label
        NewheightEditFieldLabel    matlab.ui.control.Label
        NewHeightEditField         matlab.ui.control.NumericEditField
        NewwidthEditFieldLabel     matlab.ui.control.Label
        NewWidthEditField          matlab.ui.control.NumericEditField
        pxLabel                    matlab.ui.control.Label
        pxLabel_2                  matlab.ui.control.Label
        ResizeButton               matlab.ui.control.Button
        RotationLabel              matlab.ui.control.Label
        RotationLabel_2            matlab.ui.control.Label
        RotationSlider             matlab.ui.control.Slider
        RotateButton               matlab.ui.control.Button
        RotationEditFieldLabel     matlab.ui.control.Label
        RotationEditField          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        originalImage % Description
        editingImage % Description
        RGB % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
        
        function results = updateImage(app, image)
            app.editingImage = image;
            [height, width, depth] = size(app.editingImage);
            app.heightLabel.Text = int2str(height) + " px";
            app.widthLabel.Text = int2str(width) + " px";
            imshow(app.editingImage,'Parent',app.Image1);
        end
    end
    
    methods (Access = private)
        
        function results = reset(app)
            app.SaturationSlider.Enable = 'off';
            app.LightSlider.Enable = 'off';
            app.HueSlider.Enable = 'off';
            app.RedSlider.Enable = 'off';
            app.GreenSlider.Enable = 'off';
            app.BlueSlider.Enable = 'off';
            app.HueCheckBox.Value = 0;
            app.LightCheckBox.Value = 0;
            app.SaturationCheckBox.Value = 0;
            app.RedCheckBox.Value = 0;
            app.GreenCheckBox.Value = 0;
            app.BlueCheckBox.Value = 0;
            app.RotationSlider.Value = 0;
            app.RotationEditField.Value = 0;
            
            app.SaturationSlider.Value = 0;
            app.LightSlider.Value = 0;
            app.HueSlider.Value = 0;
            app.RedSlider.Value = 0;
            app.GreenSlider.Value = 0;
            app.BlueSlider.Value = 0;
            if (app.RGB == true) 
                app.HueCheckBox.Enable = 'off';
                app.LightCheckBox.Enable = 'off';
                app.SaturationCheckBox.Enable = 'off';
            else
                app.RedCheckBox.Enable = 'off';
                app.GreenCheckBox.Enable = 'off';
                app.BlueCheckBox.Enable = 'off';
            end
     
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func)
            currImage = imread('peppers.png');
            app.originalImage = currImage;
            app.RGB = true;
            updateImage(app, currImage)
            
            reset(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.HSLButton
                app.RedSlider.Enable = 'off';
                app.GreenSlider.Enable = 'off';
                app.BlueSlider.Enable = 'off';
                app.HueCheckBox.Enable = 'on';
                app.LightCheckBox.Enable = 'on';
                app.SaturationCheckBox.Enable = 'on';
                app.editingImage = RGBtoHSL(app.editingImage);
                app.RGB = false;
            else
                app.RedSlider.Enable = 'on';
                app.GreenSlider.Enable = 'on';
                app.BlueSlider.Enable = 'on';
                if (app.RGB == false)
                    app.RGB = true;
                end
                reset(app);
            end
        end

        % Value changed function: HueSlider
        function HueSliderValueChanged(app, event)
            value = app.HueSlider.Value / 100;
            app.editingImage(:,:,1) = value * 255.0; 
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if (app.RGB == false)
                imToShow = HSLtoRGB(app.editingImage);
                imshow(imToShow,'Parent',app.Image1);
            else
                imshow(app.editingImage, 'Parent',app.Image1);
            end
        end

        % Value changed function: HueCheckBox
        function HueCheckBoxValueChanged(app, event)
            value = app.HueCheckBox.Value;
            if (value == 1)
                app.HueSlider.Enable = 'on'; 
            else
                app.HueSlider.Enable = 'off';
            end
        end

        % Value changed function: SaturationCheckBox
        function SaturationCheckBoxValueChanged(app, event)
            value = app.SaturationCheckBox.Value;
            if (value == 1)
                app.SaturationSlider.Enable = 'on'; 
            else
                app.SaturationSlider.Enable = 'off';
            end
        end

        % Value changed function: LightCheckBox
        function LightCheckBoxValueChanged(app, event)
            value = app.LightCheckBox.Value;
            if (value == 1)
                app.LightSlider.Enable = 'on'; 
            else
                app.LightSlider.Enable = 'off';
            end
            
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            value = app.SaturationSlider.Value / 100;
            app.editingImage(:,:,2) = value * 255.0; 
        end

        % Value changed function: LightSlider
        function LightSliderValueChanged(app, event)
            value = app.LightSlider.Value / 100;
            app.editingImage(:,:,3) = value * 255.0; 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.editingImage = app.originalImage;
            imshow(app.editingImage,'Parent',app.Image1);
            reset(app)
        end

        % Value changed function: RedCheckBox
        function RedCheckBoxValueChanged(app, event)
            value = app.RedCheckBox.Value;
            if (value == 1)
                app.RedSlider.Enable = 'on'; 
            else
                app.RedSlider.Enable = 'off';
            end
        end

        % Value changed function: GreenCheckBox
        function GreenCheckBoxValueChanged(app, event)
            value = app.GreenCheckBox.Value;
            if (value == 1)
                app.GreenSlider.Enable = 'on'; 
            else
                app.GreenSlider.Enable = 'off';
            end
        end

        % Value changed function: BlueCheckBox
        function BlueCheckBoxValueChanged(app, event)
            value = app.BlueCheckBox.Value;
            if (value == 1)
                app.BlueSlider.Enable = 'on'; 
            else
                app.BlueSlider.Enable = 'off';
            end
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value / 100;
            app.editingImage(:,:,1) = value * 255.0; 
        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value / 100;
            app.editingImage(:,:,2) = value * 255.0; 
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value / 100;
            app.editingImage(:,:,3) = value * 255.0; 
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            height = app.NewHeightEditField.Value;
            width = app.NewWidthEditField.Value;
            [currHeight, currWidth, currDepth] = size(app.editingImage);

            [newImage, originalImage] = resize(app.editingImage, height, width);
            updateImage(app, newImage);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            newImage = rotate(app.editingImage, app.RotationSlider.Value);
            updateImage(app, newImage);
        end

        % Value changed function: RotationSlider
        function RotationSliderValueChanged(app, event)
            value = app.RotationSlider.Value;
            app.RotationEditField.Value = value;
        end

        % Value changed function: RotationEditField
        function RotationEditFieldValueChanged(app, event)
            value = app.RotationEditField.Value;
            app.RotationSlider.Value = value;
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             [file,path] = uigetfile({ ...
                 '*.png;*.jpg;*.jpeg',...
                 'Images (*.png,*.jpg,*.jpeg)'},...
                          'File Selector');
              fullPath = fullfile(path, file)
              img = imread(fullPath);
              app.originalImage = img;
              updateImage(app, img);
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            imsave(app.Image1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.Image1 = uiaxes(app.Panel_2);
            title(app.Image1, '')
            xlabel(app.Image1, '')
            ylabel(app.Image1, '')
            app.LoadImageButton = uibutton(app.Panel_2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.Panel_2, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.AdjustGraphicsPanel = uipanel(app.UIFigure);
            app.AdjustGraphicsPanel.Title = 'Adjust Graphics';
            app.HSLRGBPanel = uipanel(app.AdjustGraphicsPanel);
            app.HSLRGBPanel.Title = 'HSL/RGB';
            app.ButtonGroup = uibuttongroup(app.HSLRGBPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HSLButton = uiradiobutton(app.ButtonGroup);
            app.HSLButton.Text = 'HSL';
            app.RGBButton = uiradiobutton(app.ButtonGroup);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.SaturationSliderLabel = uilabel(app.HSLRGBPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.HSLRGBPanel);
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.LightSliderLabel = uilabel(app.HSLRGBPanel);
            app.LightSliderLabel.Text = {'Light'; ''};
            app.LightSlider = uislider(app.HSLRGBPanel);
            app.LightSlider.ValueChangedFcn = createCallbackFcn(app, @LightSliderValueChanged, true);
            app.HueSliderLabel = uilabel(app.HSLRGBPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.HSLRGBPanel);
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @HueSliderValueChanged, true);
            app.RedSliderLabel = uilabel(app.HSLRGBPanel);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.HSLRGBPanel);
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.HSLRGBPanel);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.HSLRGBPanel);
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.HSLRGBPanel);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.HSLRGBPanel);
            app.BlueSlider.MajorTicks = [0 20 40 60 80 100];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            app.ApplyButton = uibutton(app.HSLRGBPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ResetButton = uibutton(app.HSLRGBPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.HueCheckBox = uicheckbox(app.HSLRGBPanel);
            app.HueCheckBox.ValueChangedFcn = createCallbackFcn(app, @HueCheckBoxValueChanged, true);
            app.HueCheckBox.Enable = 'off';
            app.HueCheckBox.Text = '';
            app.SaturationCheckBox = uicheckbox(app.HSLRGBPanel);
            app.SaturationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaturationCheckBoxValueChanged, true);
            app.SaturationCheckBox.Enable = 'off';
            app.SaturationCheckBox.Text = '';
            app.LightCheckBox = uicheckbox(app.HSLRGBPanel);
            app.LightCheckBox.ValueChangedFcn = createCallbackFcn(app, @LightCheckBoxValueChanged, true);
            app.LightCheckBox.Enable = 'off';
            app.LightCheckBox.Text = '';
            app.RedCheckBox = uicheckbox(app.HSLRGBPanel);
            app.RedCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedCheckBoxValueChanged, true);
            app.RedCheckBox.Tooltip = {''};
            app.RedCheckBox.Text = '';
            app.GreenCheckBox = uicheckbox(app.HSLRGBPanel);
            app.GreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @GreenCheckBoxValueChanged, true);
            app.GreenCheckBox.Text = '';
            app.BlueCheckBox = uicheckbox(app.HSLRGBPanel);
            app.BlueCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlueCheckBoxValueChanged, true);
            app.BlueCheckBox.Text = '';
            app.SizeRotationPanel = uipanel(app.AdjustGraphicsPanel);
            app.SizeRotationPanel.Title = 'Size/Rotation';
            app.ContentAwareResizingLabel = uilabel(app.SizeRotationPanel);
            app.ContentAwareResizingLabel.Text = {'Content-Aware Resizing'; ''};
            app.CurrentHeightLabel = uilabel(app.SizeRotationPanel);
            app.CurrentHeightLabel.Text = {'Current Height:'; ''};
            app.CurrentWidthLabel = uilabel(app.SizeRotationPanel);
            app.CurrentWidthLabel.Text = 'Current Width:';
            app.heightLabel = uilabel(app.SizeRotationPanel);
            app.heightLabel.Text = '';
            app.widthLabel = uilabel(app.SizeRotationPanel);
            app.widthLabel.Text = '';
            app.NewheightEditFieldLabel = uilabel(app.SizeRotationPanel);
            app.NewheightEditFieldLabel.Text = 'New height';
            app.NewHeightEditField = uieditfield(app.SizeRotationPanel, 'numeric');
            app.NewwidthEditFieldLabel = uilabel(app.SizeRotationPanel);
            app.NewwidthEditFieldLabel.Text = 'New width';
            app.NewWidthEditField = uieditfield(app.SizeRotationPanel, 'numeric');
            app.pxLabel = uilabel(app.SizeRotationPanel);
            app.pxLabel.Text = 'px';
            app.pxLabel_2 = uilabel(app.SizeRotationPanel);
            app.pxLabel_2.Text = 'px';
            app.ResizeButton = uibutton(app.SizeRotationPanel, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Text = 'Resize';
            app.RotationLabel = uilabel(app.SizeRotationPanel);
            app.RotationLabel.Text = 'Rotation';
            app.RotationLabel_2 = uilabel(app.SizeRotationPanel);
            app.RotationLabel_2.Text = 'Rotation (°)';
            app.RotationSlider = uislider(app.SizeRotationPanel);
            app.RotationSlider.Limits = [0 360];
            app.RotationSlider.MajorTicks = [0 90 180 270 360];
            app.RotationSlider.ValueChangedFcn = createCallbackFcn(app, @RotationSliderValueChanged, true);
            app.RotateButton = uibutton(app.SizeRotationPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.RotationEditFieldLabel = uilabel(app.SizeRotationPanel);
            app.RotationEditFieldLabel.Text = 'Rotation (°)';
            app.RotationEditField = uieditfield(app.SizeRotationPanel, 'numeric');
            app.RotationEditField.ValueChangedFcn = createCallbackFcn(app, @RotationEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end